classdef GUIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        EditDesignPoint                matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        EngineParametersTab            matlab.ui.container.Tab
        OnDesignInputsLabel            matlab.ui.control.Label
        OffDesignGraphicSetupLabel     matlab.ui.control.Label
        RUNANALYSISButton              matlab.ui.control.Button
        YearDesignedEditFieldLabel     matlab.ui.control.Label
        YearDesignedEditField          matlab.ui.control.NumericEditField
        BypassRatioEditFieldLabel      matlab.ui.control.Label
        BypassRatioEditField           matlab.ui.control.NumericEditField
        BleedRatioEditFieldLabel       matlab.ui.control.Label
        BleedRatioEditField            matlab.ui.control.NumericEditField
        PTOHighWEditFieldLabel         matlab.ui.control.Label
        PTOHighWEditField              matlab.ui.control.NumericEditField
        PTOLowWEditFieldLabel          matlab.ui.control.Label
        PTOLowWEditField               matlab.ui.control.NumericEditField
        InletDiameterEditFieldLabel    matlab.ui.control.Label
        InletDiameterEditField         matlab.ui.control.NumericEditField
        MaximumAltitudeEditFieldLabel  matlab.ui.control.Label
        MaximumAltitudeEditField       matlab.ui.control.NumericEditField
        MaximumMachNumberEditFieldLabel  matlab.ui.control.Label
        MaximumMachNumberEditField     matlab.ui.control.NumericEditField
        NumberofCurvesDesiredSpinnerLabel  matlab.ui.control.Label
        NumberofCurvesDesiredSpinner   matlab.ui.control.Spinner
        MaxBurnerTempEditFieldLabel    matlab.ui.control.Label
        MaxBurnerTempEditField         matlab.ui.control.NumericEditField
        Knob                           matlab.ui.control.DiscreteKnob
        TotalCompressorPressureRatioSliderLabel  matlab.ui.control.Label
        TotalCompressorPressureRatioSlider  matlab.ui.control.Slider
        PercentageHPCompressorSliderLabel  matlab.ui.control.Label
        PercentageHPCompressorSlider   matlab.ui.control.Slider
        DesignPointAltitudeEditFieldLabel  matlab.ui.control.Label
        DesignPointAltitudeEditField   matlab.ui.control.NumericEditField
        DesignPointMachEditFieldLabel  matlab.ui.control.Label
        DesignPointMachEditField       matlab.ui.control.NumericEditField
        TakeoffThrustEditFieldLabel    matlab.ui.control.Label
        TakeoffThrustEditField         matlab.ui.control.NumericEditField
        TakeoffSFCEditFieldLabel       matlab.ui.control.Label
        TakeoffSFCEditField            matlab.ui.control.NumericEditField
        InletDiameterUnit              matlab.ui.control.EditField
        TOThrustUnit                   matlab.ui.control.EditField
        MaxAltUnit                     matlab.ui.control.EditField
        maxburnertempunit              matlab.ui.control.EditField
        DPAltUnit                      matlab.ui.control.EditField
        GraphicsTab                    matlab.ui.container.Tab
        UIAxesF                        matlab.ui.control.UIAxes
        UIAxesSFC                      matlab.ui.control.UIAxes
        Switch                         matlab.ui.control.ToggleSwitch
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNANALYSISButton
        function RUNANALYSISButtonPushed(app, event)
            clc
            
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Running Engine Performance Analysis');
            pause(.1)


            M0 = app.DesignPointMachEditField.Value;
            
            pi_c = app.TotalCompressorPressureRatioSlider.Value;
            ratio = app.PercentageHPCompressorSlider.Value;
            root = nthroot(pi_c,100);
            pi_cH = root^ratio;
            pi_cL = root^(100-ratio);
            
            if strcmp(app.Knob.Value,'English')
                D0 = convlength(app.InletDiameterEditField.Value,'ft','m');
                A0 = pi*(D0/2)^2;
                To_burner = convtemp(app.MaxBurnerTempEditField.Value,'R','K');
                alt = app.DesignPointAltitudeEditField.Value;
            elseif strcmp(app.Knob.Value,'Metric')
                D0 = app.InletDiameterEditField.Value;
                A0 = pi*(D0/2)^2;
                To_burner = app.MaxBurnerTempEditField.Value;
                alt = convlength(app.DesignPointAltitudeEditField.Value,'m','ft');
            end
            
            
            alpha = app.BypassRatioEditField.Value;
            [pi_f] = optimalfan(alpha);
            beta = app.BleedRatioEditField.Value;
            PtoH = app.PTOHighWEditField.Value;
            PtoL = app.PTOLowWEditField.Value;
            year = app.YearDesignedEditField.Value;
            
            T_takeoff = app.TakeoffThrustEditField.Value;
            SFC_takeoff = app.TakeoffSFCEditField.Value;
            
            
            [state,component,design,inputs,~] = on_design(alt,M0,pi_f,pi_cL,pi_cH,alpha,beta,PtoH,PtoL,A0,year,To_burner);


            if strcmp(app.Knob.Value,'English')
                An = app.MaximumAltitudeEditField.Value;
            elseif strcmp(app.Knob.Value,'Metric')
                An = convlength(app.MaximumAltitudeEditField.Value,'m','ft');
            end
            Mn = app.MaximumMachNumberEditField.Value;
            n = app.NumberofCurvesDesiredSpinner.Value;
            
            altft = linspace(0,An,n);
            alt = altft./3.281;
            M0 = linspace(.1,Mn,20); %Operational envelope
            
            S = zeros(size(alt));
            F_N = zeros(size(alt));
            
            for i = 1:length(alt)
                for j = 1:length(M0)
                    
                    [~,~,~,~,performance_i] = off_design2(state,component,design,inputs,M0(j),alt(i),A0);
                    
                    S(i,j) = performance_i{2,2} / ((.453592/3600)/4.44822);
                    F_N(i,j) = performance_i{2,1}; %[N]
                    d.Value = i/length(alt);
                    d.Message = 'Running Engine Performance Analysis';
                    
                end
            end
            
            S_scaled = SFC_takeoff/S(1,1).*S;
            F_scaled_N= T_takeoff/F_N(1,1).*F_N;
            F_scaled_lbf = convforce(F_scaled_N,'N','lbf');
            
            % Plotting
            d.Message = 'Plotting Graphics';
            pause(.5)
            
            if strcmp(app.Knob.Value,'Metric')
                for i = 1:length(alt)
                    plot(app.UIAxesF,M0,F_scaled_N(i,:),'linewidth',1.5)
                end
                ylabel(app.UIAxesF,'Thrust (kN)')
                legend(app.UIAxesF,string(round(altft)));
            end
            if strcmp(app.Knob.Value,'English')
                for i = 1:length(alt)
                    plot(app.UIAxesF,M0,F_scaled_lbf(i,:),'linewidth',1.5)
                end
                ylabel(app.UIAxesF,'Thrust (kN)')
                legend(app.UIAxesF,string(round(altft)));
            end


            for i = 1:length(alt)
                plot(app.UIAxesSFC,M0,S_scaled(i,:),'linewidth',1.5)
            end
            legend(app.UIAxesSFC,string(round(altft)))


            % Closing
            close(f);
            app.TabGroup.SelectedTab = app.GraphicsTab;
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            clc
            
            if strcmp(app.Knob.Value,'English')
                app.MaximumAltitudeEditField.Value = round(convlength(app.MaximumAltitudeEditField.Value,'m','ft'));
                app.MaxAltUnit.Value = 'ft';
                app.InletDiameterEditField.Value = (convlength(app.InletDiameterEditField.Value,'m','ft'));
                app.InletDiameterUnit.Value = 'ft';
                app.DesignPointAltitudeEditField.Value = round(convlength(app.DesignPointAltitudeEditField.Value,'m','ft'));
                app.DPAltUnit.Value = 'ft';
                app.MaxBurnerTempEditField.Value = (convtemp(app.MaxBurnerTempEditField.Value,'K','R'));
                app.maxburnertempunit.Value = 'R';
                app.TakeoffThrustEditField.Value = app.TakeoffThrustEditField.Value*1000;
                app.TakeoffThrustEditField.Value = (convforce(app.TakeoffThrustEditField.Value,'N','lbf'));
                app.TOThrustUnit.Value = 'lbf';
            end
            
            if strcmp(app.Knob.Value,'Metric')
                app.MaximumAltitudeEditField.Value = round(convlength(app.MaximumAltitudeEditField.Value,'ft','m'));
                app.MaxAltUnit.Value = 'm';
                app.InletDiameterEditField.Value = (convlength(app.InletDiameterEditField.Value,'ft','m'));
                app.InletDiameterUnit.Value = 'm';
                app.DesignPointAltitudeEditField.Value = round(convlength(app.DesignPointAltitudeEditField.Value,'ft','m'));
                app.DPAltUnit.Value = 'm';
                app.MaxBurnerTempEditField.Value = (convtemp(app.MaxBurnerTempEditField.Value,'R','K'));
                app.maxburnertempunit.Value = 'K';
                app.TakeoffThrustEditField.Value = app.TakeoffThrustEditField.Value/1000;
                app.TakeoffThrustEditField.Value = (convforce(app.TakeoffThrustEditField.Value,'lbf','N'));
                app.TOThrustUnit.Value = 'N';
            end
        end

        % Menu selected function: EditDesignPoint
        function EditDesignPointMenuSelected(app, event)
               % Create DesignPointAltitudeEditFieldLabel
     
            app.DesignPointAltitudeEditFieldLabel.FontColor = [0 0 0];
            app.DesignPointAltitudeEditField.FontColor = [0 0 0];
            app.DesignPointAltitudeEditField.BackgroundColor = [1 1 1];
            app.DesignPointMachEditFieldLabel.FontColor = [0 0 0];
            app.DesignPointMachEditField.FontColor = [0 0 0];
            app.DesignPointMachEditField.BackgroundColor = [1 1 1];
            app.DPAltUnit.BackgroundColor = [1 1 1];
            app.DPAltUnit.FontColor = [0 0 0];
            
           app.DesignPointMachEditField.Editable = 'on';
           app.DesignPointAltitudeEditField.Editable = 'on';
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.EditDesignPoint = uimenu(app.EditMenu);
            app.EditDesignPoint.MenuSelectedFcn = createCallbackFcn(app, @EditDesignPointMenuSelected, true);
            app.EditDesignPoint.Text = 'Edit Design Point';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EngineParametersTab = uitab(app.TabGroup);
            app.EngineParametersTab.Title = 'Engine Parameters';
            app.OnDesignInputsLabel = uilabel(app.EngineParametersTab);
            app.OnDesignInputsLabel.Text = 'On-Design Inputs';
            app.OffDesignGraphicSetupLabel = uilabel(app.EngineParametersTab);
            app.OffDesignGraphicSetupLabel.Text = 'Off-Design Graphic Setup';
            app.RUNANALYSISButton = uibutton(app.EngineParametersTab, 'push');
            app.RUNANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @RUNANALYSISButtonPushed, true);
            app.RUNANALYSISButton.Text = 'RUN ANALYSIS';
            app.YearDesignedEditFieldLabel = uilabel(app.EngineParametersTab);
            app.YearDesignedEditFieldLabel.Text = 'Year Designed';
            app.YearDesignedEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.YearDesignedEditField.Value = 2011;
            app.BypassRatioEditFieldLabel = uilabel(app.EngineParametersTab);
            app.BypassRatioEditFieldLabel.Text = 'Bypass Ratio';
            app.BypassRatioEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.BypassRatioEditField.Value = 6.9;
            app.BleedRatioEditFieldLabel = uilabel(app.EngineParametersTab);
            app.BleedRatioEditFieldLabel.Text = 'Bleed Ratio';
            app.BleedRatioEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.PTOHighWEditFieldLabel = uilabel(app.EngineParametersTab);
            app.PTOHighWEditFieldLabel.Text = 'PTO High (W)';
            app.PTOHighWEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.PTOLowWEditFieldLabel = uilabel(app.EngineParametersTab);
            app.PTOLowWEditFieldLabel.Text = 'PTO Low (W)';
            app.PTOLowWEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.InletDiameterEditFieldLabel = uilabel(app.EngineParametersTab);
            app.InletDiameterEditFieldLabel.Text = 'Inlet Diameter';
            app.InletDiameterEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.InletDiameterEditField.Value = 0.469;
            app.MaximumAltitudeEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MaximumAltitudeEditFieldLabel.Text = 'Maximum Altitude';
            app.MaximumAltitudeEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MaximumAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.MaximumAltitudeEditField.Value = 7620;
            app.MaximumMachNumberEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MaximumMachNumberEditFieldLabel.Text = 'Maximum Mach Number';
            app.MaximumMachNumberEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MaximumMachNumberEditField.ValueDisplayFormat = '%.3f';
            app.MaximumMachNumberEditField.Value = 0.45;
            app.NumberofCurvesDesiredSpinnerLabel = uilabel(app.EngineParametersTab);
            app.NumberofCurvesDesiredSpinnerLabel.Text = 'Number of Curves Desired';
            app.NumberofCurvesDesiredSpinner = uispinner(app.EngineParametersTab);
            app.NumberofCurvesDesiredSpinner.RoundFractionalValues = 'on';
            app.NumberofCurvesDesiredSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofCurvesDesiredSpinner.Value = 3;
            app.MaxBurnerTempEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MaxBurnerTempEditFieldLabel.Text = 'Max Burner Temp';
            app.MaxBurnerTempEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MaxBurnerTempEditField.Value = 1294;
            app.Knob = uiknob(app.EngineParametersTab, 'discrete');
            app.Knob.Items = {'Metric', 'English'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Metric';
            app.TotalCompressorPressureRatioSliderLabel = uilabel(app.EngineParametersTab);
            app.TotalCompressorPressureRatioSliderLabel.Text = 'Total Compressor Pressure Ratio';
            app.TotalCompressorPressureRatioSlider = uislider(app.EngineParametersTab);
            app.TotalCompressorPressureRatioSlider.Limits = [15 45];
            app.TotalCompressorPressureRatioSlider.MajorTicks = [15 20 25 30 35 40 45];
            app.TotalCompressorPressureRatioSlider.Value = 24;
            app.PercentageHPCompressorSliderLabel = uilabel(app.EngineParametersTab);
            app.PercentageHPCompressorSliderLabel.Text = 'Percentage HP Compressor';
            app.PercentageHPCompressorSlider = uislider(app.EngineParametersTab);
            app.PercentageHPCompressorSlider.Limits = [30 90];
            app.PercentageHPCompressorSlider.MajorTicks = [30 40 50 60 70 80 90];
            app.PercentageHPCompressorSlider.Value = 60;
            app.DesignPointAltitudeEditFieldLabel = uilabel(app.EngineParametersTab);
            app.DesignPointAltitudeEditFieldLabel.Text = 'Design Point Altitude';
            app.DesignPointAltitudeEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.DesignPointAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.DesignPointAltitudeEditField.Editable = 'off';
            app.DesignPointMachEditFieldLabel = uilabel(app.EngineParametersTab);
            app.DesignPointMachEditFieldLabel.Text = 'Design Point Mach';
            app.DesignPointMachEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.DesignPointMachEditField.Editable = 'off';
            app.DesignPointMachEditField.Value = 0.1;
            app.TakeoffThrustEditFieldLabel = uilabel(app.EngineParametersTab);
            app.TakeoffThrustEditFieldLabel.Text = 'Takeoff Thrust';
            app.TakeoffThrustEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.TakeoffThrustEditField.ValueDisplayFormat = '%.3f';
            app.TakeoffThrustEditField.Value = 2.8418;
            app.TakeoffSFCEditFieldLabel = uilabel(app.EngineParametersTab);
            app.TakeoffSFCEditFieldLabel.Text = 'Takeoff SFC';
            app.TakeoffSFCEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.TakeoffSFCEditField.ValueDisplayFormat = '%.3f';
            app.TakeoffSFCEditField.Value = 0.53;
            app.maxburnertempunit = uieditfield(app.EngineParametersTab, 'text');
            app.maxburnertempunit.Value = 'K';
            app.InletDiameterUnit = uieditfield(app.EngineParametersTab, 'text');
            app.InletDiameterUnit.Value = 'm';
            app.TOThrustUnit = uieditfield(app.EngineParametersTab, 'text');
            app.TOThrustUnit.Value = 'kN';
            app.MaxAltUnit = uieditfield(app.EngineParametersTab, 'text');
            app.MaxAltUnit.Value = 'm';
            app.DPAltUnit = uieditfield(app.EngineParametersTab, 'text');
            app.DPAltUnit.Editable = 'off';
            app.DPAltUnit.Value = 'm';
            app.GraphicsTab = uitab(app.TabGroup);
            app.GraphicsTab.Title = 'Graphics';
            app.UIAxesF = uiaxes(app.GraphicsTab);
            title(app.UIAxesF, 'Thrust vs. Mach Number')
            xlabel(app.UIAxesF, 'Mach Number')
            ylabel(app.UIAxesF, 'Thrust')
            app.UIAxesSFC = uiaxes(app.GraphicsTab);
            title(app.UIAxesSFC, 'SFC vs. Mach Number')
            xlabel(app.UIAxesSFC, 'Mach Number')
            ylabel(app.UIAxesSFC, 'SFC')
            app.Switch = uiswitch(app.GraphicsTab, 'toggle');
            app.Switch.Items = {'Metric', 'English'};
            app.Switch.Value = 'Metric';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end